classdef RoboespasApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RoboespasAppUIFigure            matlab.ui.Figure
        MenuTabGroup                    matlab.ui.container.TabGroup
        CapturarTab                     matlab.ui.container.Tab
        MoverIniLabel                   matlab.ui.control.Label
        PersonalizarIniLabel            matlab.ui.control.Label
        GrabarFlexionLabel              matlab.ui.control.Label
        MoverIzqdaButton                matlab.ui.control.Button
        MoverDchaButton                 matlab.ui.control.Button
        GravedadCompensadaButtonGroup   matlab.ui.container.ButtonGroup
        IniBloquearRobotButton          matlab.ui.control.ToggleButton
        LiberarXButton                  matlab.ui.control.ToggleButton
        LiberarYButton                  matlab.ui.control.ToggleButton
        LiberarZButton                  matlab.ui.control.ToggleButton
        CapturarButtonGroup             matlab.ui.container.ButtonGroup
        CapturarBloquearButton          matlab.ui.control.ToggleButton
        IniciarCapturaButton            matlab.ui.control.ToggleButton
        FinalizarCapturaButton          matlab.ui.control.ToggleButton
        GenerarFlexoextensionLabel      matlab.ui.control.Label
        AjustarTrayectoriaButton        matlab.ui.control.Button
        TrayectoriasCapturadasDropdown  matlab.ui.control.DropDown
        EMGAyudaCapturarImage           matlab.ui.control.Image
        RepetirTab                      matlab.ui.container.Tab
        AjustesLabel                    matlab.ui.control.Label
        EjecutarReferenciaLabel         matlab.ui.control.Label
        PosicionInicialReferenciaButton  matlab.ui.control.Button
        EjecutarTrayectoriaReferenciaButton  matlab.ui.control.Button
        EjecutarTrayectoriaLabel        matlab.ui.control.Label
        PosicionInicialRepetirButton    matlab.ui.control.Button
        EjecutarTrayectoriaButton       matlab.ui.control.Button
        RepeticionesSpinner             matlab.ui.control.Spinner
        VelocidadSlider                 matlab.ui.control.Slider
        VelocidadLabel                  matlab.ui.control.Label
        TrayectoriasAjustadasDropdown   matlab.ui.control.DropDown
        SeleccionarTrayectoriaLabel     matlab.ui.control.Label
        TentreflexionyextensionsLabel   matlab.ui.control.Label
        PausaEntreFlexionExtension      matlab.ui.control.NumericEditField
        TentreflexoextensionessLabel    matlab.ui.control.Label
        PausaEntreFlexoextensiones      matlab.ui.control.Spinner
        EMGTiempoRealButton             matlab.ui.control.StateButton
        EMGAyudaRepetirImage            matlab.ui.control.Image
        ResultadosTab                   matlab.ui.container.Tab
        ResultsPanel                    matlab.ui.container.Panel
        TrayectoriaIIWALabel            matlab.ui.control.Label
        ArticularLabel                  matlab.ui.control.Label
        qButton                         matlab.ui.control.Button
        dqButton                        matlab.ui.control.Button
        ErqButton                       matlab.ui.control.Button
        ErdqButton                      matlab.ui.control.Button
        CartesianaLabel                 matlab.ui.control.Label
        xButton                         matlab.ui.control.Button
        ErxButton                       matlab.ui.control.Button
        ErdxButton                      matlab.ui.control.Button
        TresDButton                     matlab.ui.control.Button
        dxButton                        matlab.ui.control.Button
        SensorFuerzaParLabel            matlab.ui.control.Label
        FTButton                        matlab.ui.control.Button
        SensoresDelsysLabel             matlab.ui.control.Label
        EMGButton                       matlab.ui.control.Button
        AceleracionesButton             matlab.ui.control.Button
        VelocidadAngularButton          matlab.ui.control.Button
        ddqButton                       matlab.ui.control.Button
        SeleccionelarepeticinamostrarLabel  matlab.ui.control.Label
        RepeticionMostradaSpinner       matlab.ui.control.Spinner
        EpxButton                       matlab.ui.control.Button
        EpdxButton                      matlab.ui.control.Button
        RepetibilidadCartesianaLabel    matlab.ui.control.Label
        PrecisionCartesianaLabel        matlab.ui.control.Label
        RepetibilidadArticularLabel     matlab.ui.control.Label
        PrecisionArticularLabel         matlab.ui.control.Label
        EpqButton                       matlab.ui.control.Button
        EpdqButton                      matlab.ui.control.Button
        TqButton                        matlab.ui.control.Button
        FTIIWAButton                    matlab.ui.control.Button
        Repeticion0Info                 matlab.ui.control.Label
        PacientePanel                   matlab.ui.container.Panel
        IDSpinnerLabel                  matlab.ui.control.Label
        IDSpinner                       matlab.ui.control.Spinner
        BrazoPanel                      matlab.ui.container.Panel
        BrazoSwitch                     matlab.ui.control.Switch
        BrazoSwitchDLabel               matlab.ui.control.Label
        BrazoSwitchILabel               matlab.ui.control.Label
        IiwaCommandPanel                matlab.ui.container.Panel
        IiwaCommandSwitch               matlab.ui.control.Switch
        FRILabel                        matlab.ui.control.Label
        StackLabel                      matlab.ui.control.Label
        ConexionesPanel                 matlab.ui.container.Panel
        IIWACheckBox                    matlab.ui.control.CheckBox
        DelsysCheckBox                  matlab.ui.control.CheckBox
        FTSensorCheckBox                matlab.ui.control.CheckBox
        RoboespasLogo                   matlab.ui.control.Image
        UC3MLogo                        matlab.ui.control.Image
        SensoresDelsysPanel             matlab.ui.container.Panel
        ImagenS1                        matlab.ui.control.Image
        ImagenS2                        matlab.ui.control.Image
        ImagenS3                        matlab.ui.control.Image
        ImagenS4                        matlab.ui.control.Image
        S3BrachioradialisLabel          matlab.ui.control.Label
        S4FlexorCarpiRadialisLabel      matlab.ui.control.Label
        S1BicepsBrachiiLabel            matlab.ui.control.Label
        S2TricepsLateralHeadLabel       matlab.ui.control.Label
        ColocacionSensoresDelsysLabel   matlab.ui.control.Label
        GraficasPanel                   matlab.ui.container.Panel
    end

%% PROPERTIES %%

    %Constant app properties
    properties(Constant)
        %Path donde se guardan los trials capturados
        TrialsDatabaseFolderPath = [cell2mat(extractBetween(fileparts(which(mfilename)), 1, strfind(fileparts(which(mfilename)), 'RoboespasApp')+strlength('RoboespasApp'))), 'Results/RoboespasTrials/CurrentTrials/'];
        %Path donde se guardan los backups de los trials
        TrialsDatabaseBackupFolderPath = [cell2mat(extractBetween(fileparts(which(mfilename)), 1, strfind(fileparts(which(mfilename)), 'RoboespasApp')+strlength('RoboespasApp'))), 'Results/RoboespasTrials/Backups/'];
        FrecuenciaBackup = 5;
        TrajectorySelectStr = {'Seleccione una trayectoria'};
        MovementSelectStr = {'Seleccione un movimiento'};
        MaxSensors = 6;
        NameMovement = 'flexext'
        DefaultVelocity = 15; % Repeat all trajectories at 15º/s unless slider is changed
    end
    
    %Private variables
    properties (Access = private)
        IIWARobotObject
        FTSensorObject
        DelsysSensorsObject
        TimeWaitBetweenRepetitions = 6 

        TrialsFile
        ExecutionTimes = {};
    end
    
%% FUNCTIONS %%
    methods (Access = private)
                
        % Update Dropdowns functions
        function ActualizarTrayectoriasCapturadas(app)
        % Función que actualiza el dropdown de trayectorias capturadas
        % guardadas obteniendo de IIWARobot
            try
                app.TrayectoriasCapturadasDropdown.Items=[app.TrajectorySelectStr, app.IIWARobotObject.DataInputList();];
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end
  
        function ActualizarTrayectoriasAjustadas(app)
            try
                app.TrayectoriasAjustadasDropdown.Items=[app.TrajectorySelectStr, app.IIWARobotObject.DataAdjustedList()];
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        function ActualizarBrazoSeleccionado(app)
            if (strcmp(app.BrazoSwitch.Value, 'I'))
                app.BrazoSwitchILabel.Enable='on';
                app.BrazoSwitchDLabel.Enable='off';
            else
                app.BrazoSwitchILabel.Enable='off';
                app.BrazoSwitchDLabel.Enable='on';
            end        
        end
        
        function GuardarTrial(app, i_trial)
            try
                tic
                [ ~, ~ ] = mkdir(app.TrialsDatabaseFolderPath);
                ntrials = length(dir(app.TrialsDatabaseFolderPath))-2;
                [ ~, ~ ] = mkdir(app.TrialsDatabaseBackupFolderPath);
                Trial.IDPatient = app.IDSpinner.Value;
                Trial.Conditions.NameData = app.IIWARobotObject.NameData;
                Trial.Conditions.Arm = app.BrazoSwitch.Value;
                Trial.Conditions.ExecutionVelocityDPS = app.VelocidadSlider.Value;
                Trial.Conditions.DateCaptured = app.IIWARobotObject.CapturedDate;
                Trial.Conditions.DateExecuted = datestr(now, 'yymmdd_HHMMSS');
                Trial.Trajectory=app.IIWARobotObject.GetStruct(i_trial);
                Trial.FTSensor=app.FTSensorObject.GetStruct(i_trial);
                Trial.DelsysSensors=app.DelsysSensorsObject.GetStruct(i_trial);
                
                if (~isempty(app.ExecutionTimes))
                    Trial.ExecutionTimes = app.ExecutionTimes{i_trial}; 
                else
                    Trial.ExecutionTimes=[];
                end
                trial_filepath = [app.TrialsDatabaseFolderPath, num2str(Trial.IDPatient), '_', Trial.Conditions.Arm, '_', Trial.Conditions.DateExecuted];
                new_trial_file = matfile(trial_filepath, 'Writable', true);
                new_trial_file.Trial = Trial;
                disp(['Salvado el trial ', num2str(ntrials)]);
                if (mod(ntrials,app.FrecuenciaBackup) == 0) %Save backup every trial
                    backup_folderpath = [app.TrialsDatabaseBackupFolderPath, 'Backup', datestr(now, '_yyyy_mm_dd_HH_MM'), '\'];
                    [ ~, ~ ] = mkdir(backup_folderpath);
                    copyfile(app.TrialsDatabaseFolderPath, backup_folderpath);
                    disp(['Backup de los trials actuales salvado en ', backup_folderpath]);
                end
                toc
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end
        
        function AjustarTrayectoriaVelocidadActual(app)
            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Ajustando trayectoria a la velocidad requerida');
            try
                app.VelocidadSlider.Value = round(app.VelocidadSlider.Value/5)*5;
                prg.Message=['Ajustando trayectoria a velocidad ', num2str(app.VelocidadSlider.Value), char(176), '/s'];
                app.IIWARobotObject.ChangeVelocity(app.VelocidadSlider.Value);
                app.VelocidadLabel.Text=[num2str(round(app.VelocidadSlider.Value,2)), ' ', char(176), '/s'];
                app.ResetRepeatButtons();
                close(prg);
            catch ME
                close(prg);
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        
        function ActivarBotonesGraficas(app, origin, value)
            if value
                state = 'on';
            else
                state = 'off';
            end
            switch(origin)
                case 'iiwa'
                    app.qButton.Enable = state;
                    app.dqButton.Enable = state;
                    app.ddqButton.Enable = state;                    
                    app.EpqButton.Enable = state;
                    app.EpdqButton.Enable = state;
                    app.TqButton.Enable = state;
                    app.xButton.Enable = state;
                    app.dxButton.Enable = state;
                    app.TresDButton.Enable = state;
                    app.EpxButton.Enable = state;
                    app.EpdxButton.Enable = state;
                    app.FTIIWAButton.Enable=state;
                case 'ft'
                    app.FTButton.Enable = state;
                case 'delsys'
                    app.EMGButton.Enable = state;
                    app.EMGTiempoRealButton.Enable = state;
                    app.AceleracionesButton.Enable = state;
                    app.VelocidadAngularButton.Enable = state;                    
            end
        end
        
        function MostrarEMGTiempoReal(app, value)
            if(value)
                app.DelsysSensorsObject.StartEMGplot(app.RepetirTab);
            else
                app.DelsysSensorsObject.StopEMGplot();
            end
        end
        
        function LimpiarGraficas(app)
            while(~isempty(app.GraficasPanel.Children))
                delete(app.GraficasPanel.Children(1))
            end
        end
        
        function ResetRepeatButtons(app)
            app.PosicionInicialRepetirButton.Enable='off';
            app.EjecutarTrayectoriaButton.Enable='off';
            app.PosicionInicialReferenciaButton.Enable='on';
            app.EjecutarTrayectoriaReferenciaButton.Enable = 'off';
        end
        function ResetCaptureButtons(app)
            app.CapturarButtonGroup.SelectedObject=app.CapturarBloquearButton;
            app.CapturarBloquearButton.Enable='off';
            app.IniciarCapturaButton.Enable='off';
            app.FinalizarCapturaButton.Enable='off';
            app.IniBloquearRobotButton.Enable='off';
            app.LiberarXButton.Enable='off';
            app.LiberarYButton.Enable='off';
            app.LiberarZButton.Enable='off';
            app.MoverIzqdaButton.Enable='on';
            app.MoverDchaButton.Enable='on';
        end
    end

%% CALLBACKS %%

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Inicializando');
            try
                %Añadir carpetas al path de Matlab
                addpath(genpath(fileparts(which(mfilename))));

                %Inicializar dispositivos
                app.IIWARobotObject = IiwaRoboespas();
                app.FTSensorObject = FTSensor(0);
                app.DelsysSensorsObject = DelsysSensors(0);
                app.IIWARobotObject.SetMovement(app.NameMovement);
                app.DelsysSensorsObject.SetMovement(app.NameMovement);

                %Inicializar GUI
                app.RoboespasAppUIFigure.WindowState = 'maximized';
                app.RoboespasLogo.ImageSource = [fileparts(which(mfilename)), '\Fotos\roboespas_logo.jpg'];
                app.UC3MLogo.ImageSource = [fileparts(which(mfilename)), '\Fotos\uc3m_logo.jpg'];
                app.ImagenS1.ImageSource = [fileparts(which(mfilename)), '\Fotos\FlexExt\S1.PNG'];
                app.ImagenS2.ImageSource = [fileparts(which(mfilename)), '\Fotos\FlexExt\S2.PNG'];
                app.ImagenS3.ImageSource = [fileparts(which(mfilename)), '\Fotos\FlexExt\S3.PNG'];
                app.ImagenS4.ImageSource = [fileparts(which(mfilename)), '\Fotos\FlexExt\S4.PNG'];
                app.ResultsPanel.Visible='off';                
                drawnow;
                
                %Cargar trayectorias de entrada y ajustadas
                app.ActualizarTrayectoriasCapturadas();
                app.ActualizarTrayectoriasAjustadas();
                
                %Inicializar velocidad a la velocidad predeterminada
                app.VelocidadLabel.Text = [num2str(app.DefaultVelocity), ' ', char(176), '/s'];
                app.VelocidadSlider.Value = app.DefaultVelocity;
                
                %Cerrar el cuadro de progreso
                close(prg);
            catch ME
                close(prg);
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Value changed function: VelocidadSlider
        function VelocidadSliderChanged(app, event)
            app.AjustarTrayectoriaVelocidadActual();
        end

        % Selection change function: MenuTabGroup
        function MenuTabGroupChanged(app, event)
            selectedTab = app.MenuTabGroup.SelectedTab;
            if (selectedTab==app.CapturarTab)
                app.IDSpinner.Enable='on';
                selection = uiconfirm(app.RoboespasAppUIFigure,'New patient?','Change IDPatient?', 'Options',{'Yes','No'},'Icon', 'question');
                prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Cargando lista de trayectorias de entrada');
                ActualizarTrayectoriasCapturadas(app);
                if (strcmp(selection,'Yes'))
                    app.IDSpinner.Value=app.IDSpinner.Value+1;
                end
                close(prg);
            elseif (selectedTab==app.RepetirTab)
                app.BrazoSwitch.Enable='off';
                prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Cargando lista de trayectorias ajustadas');
                app.PausaEntreFlexionExtension.Value = app.IIWARobotObject.CommandedMiddlePause;
                ActualizarTrayectoriasAjustadas(app);
                close(prg);
            elseif (selectedTab==app.ResultadosTab)
                app.BrazoSwitch.Enable='off';
                app.LimpiarGraficas();
            end
        end

        % Callback function
        function SmoothingValueChanged(app, event)
            app.IIWARobotObject.SetSmoothingParameters(app.CommandedSmoothing.Value);
        end

        % Button pushed function: MoverIzqdaButton
        function MoverPrefIzqdaButton(app, event)
            prg = uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Moviendose a posición de referencia izquierda');
            try
                app.IIWARobotObject.SendReferencePositionLeft();
                app.BrazoSwitch.Value='I';
                app.ActualizarBrazoSeleccionado();
                app.IniciarCapturaButton.Enable='on';
                app.LiberarXButton.Enable='on';
                app.LiberarYButton.Enable='on';
                app.LiberarZButton.Enable='on';
                close(prg);
                uialert(app.RoboespasAppUIFigure, 'Robot en posición de referencia izquierda', '', 'Icon', 'success');
            catch ME
                close(prg);
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: MoverDchaButton
        function MoverPrefDchaButton(app, event)
            prg = uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Moviendose a posición de referencia derecha');
            try
                app.IIWARobotObject.SendReferencePositionRight();
                app.BrazoSwitch.Value ='D';
                app.ActualizarBrazoSeleccionado();
                app.IniciarCapturaButton.Enable ='on';
                app.LiberarXButton.Enable ='on';
                app.LiberarYButton.Enable ='on';
                app.LiberarZButton.Enable ='on';
                close(prg);
                uialert(app.RoboespasAppUIFigure, 'Robot en posición de referencia derecha', '', 'Icon', 'success');
            catch ME
                close(prg);
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Selection changed function: CapturarButtonGroup
        function CapturarButtonGroupSelectionChanged(app, event)
            selectedButton = app.CapturarButtonGroup.SelectedObject;
            if (selectedButton == app.CapturarBloquearButton)
                prg = uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Bloqueando robot');
                try
                    app.IIWARobotObject.BlockRobot();
                    app.FinalizarCapturaButton.Enable = 'off';
                    app.CapturarBloquearButton.Enable = 'off';
                    app.LiberarXButton.Enable = 'on';
                    app.LiberarYButton.Enable = 'on';
                    app.LiberarZButton.Enable = 'on';
                    app.MoverIzqdaButton.Enable = 'on';
                    app.MoverDchaButton.Enable = 'on';
                    app.TrayectoriasCapturadasDropdown.Enable = 'on';
                    app.AjustarTrayectoriaButton.Enable = 'on';
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, 'Robot bloqueado', '', 'Icon', 'success');                    
                catch ME
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            elseif (selectedButton == app.IniciarCapturaButton)
                prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Iniciando captura');
                try
                    app.IIWARobotObject.CaptureStart( );
                    app.CapturarBloquearButton.Enable = 'off';
                    app.IniciarCapturaButton.Enable = 'off';
                    app.FinalizarCapturaButton.Enable = 'on';
                    app.LiberarXButton.Enable = 'off';
                    app.LiberarYButton.Enable = 'off';
                    app.LiberarZButton.Enable = 'off';
                    app.IniBloquearRobotButton.Enable = 'off';
                    app.MoverIzqdaButton.Enable = 'off';
                    app.MoverDchaButton.Enable = 'off';
                    app.TrayectoriasCapturadasDropdown.Enable = 'off';
                    app.AjustarTrayectoriaButton.Enable = 'off';
                    close(prg);
                catch ME
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            elseif (selectedButton == app.FinalizarCapturaButton)
                prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Finalizando captura');
                try
                    app.CapturarBloquearButton.Enable='on';
                    app.IIWARobotObject.SaveDataInput(app.IDSpinner.Value, app.BrazoSwitch.Value);
                    app.ActualizarTrayectoriasCapturadas();
                    app.TrayectoriasCapturadasDropdown.Value=app.IIWARobotObject.NameDataInput;
                    app.FinalizarCapturaButton.Enable='off';
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, ['Trayectoria grabada guardada en archivo ', app.IIWARobotObject.NameDataInput], '', 'Icon', "success");
                catch ME
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end                
            end
        end

        % Selection changed function: GravedadCompensadaButtonGroup
        function GravedadCompensadaButtonGroupSelectionChanged(app, event)
            selectedButton = app.GravedadCompensadaButtonGroup.SelectedObject;
            if (selectedButton == app.IniBloquearRobotButton)
                prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Bloqueando robot');
                try
                    app.IIWARobotObject.BlockRobot();
                    app.IniBloquearRobotButton.Enable='off';
                    app.LiberarXButton.Enable='on';
                    app.LiberarYButton.Enable='on';
                    app.LiberarZButton.Enable='on';
                    app.IniciarCapturaButton.Enable='on';
                    app.TrayectoriasCapturadasDropdown.Enable='on';
                    app.MoverIzqdaButton.Enable='on';
                    app.MoverDchaButton.Enable='on';
                    if (~strcmp(app.TrayectoriasCapturadasDropdown.Value, app.TrajectorySelectStr))
                        app.AjustarTrayectoriaButton.Enable='on';
                    end
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, 'Robot bloqueado', '', 'Icon', 'success');
                catch ME
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            else
                prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Liberando robot');
                try
                    if (selectedButton == app.LiberarXButton)
                        app.IIWARobotObject.FreeCartesianCoordinate('X');
                    elseif (selectedButton == app.LiberarYButton)
                        app.IIWARobotObject.FreeCartesianCoordinate('Y');
                    elseif (selectedButton == app.LiberarZButton)
                        app.IIWARobotObject.FreeCartesianCoordinate('Z');
                    end
                    app.LiberarXButton.Enable='off';
                    app.LiberarYButton.Enable='off';
                    app.LiberarZButton.Enable='off';
                    app.IniBloquearRobotButton.Enable='on';
                    app.IniciarCapturaButton.Enable='off';
                    app.TrayectoriasCapturadasDropdown.Enable='off';
                    app.MoverIzqdaButton.Enable='off';
                    app.MoverDchaButton.Enable='off';
                    app.AjustarTrayectoriaButton.Enable='off';
                    close(prg);
                catch ME
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            end
        end

        % Value changed function: TrayectoriasCapturadasDropdown
        function TrayectoriasCapturadasDropdownChanged(app, event)
            if (~strcmp(app.TrayectoriasCapturadasDropdown.Value, app.TrajectorySelectStr))
                prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Cargando trayectoria de entrada');
                try
                    [app.IDSpinner.Value, app.BrazoSwitch.Value] = app.IIWARobotObject.LoadInput(app.TrayectoriasCapturadasDropdown.Value);
                    app.ActualizarBrazoSeleccionado();
                    app.AjustarTrayectoriaButton.Enable='on';
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, ['Cargada trayectoria capturada ', app.TrayectoriasCapturadasDropdown.Value], '', 'Icon', 'success'); 
                catch ME
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            else
                app.AjustarTrayectoriaButton.Enable='off';
            end
        end

        % Value changed function: TrayectoriasAjustadasDropdown
        function TrayectoriasAjustadasDropdownChanged(app, event)
            if (~strcmp(app.TrayectoriasAjustadasDropdown.Value, app.TrajectorySelectStr))
                prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Cargando trayectoria ajustada');
                try
                    [app.IDSpinner.Value, app.BrazoSwitch.Value] = app.IIWARobotObject.LoadAdjusted(app.TrayectoriasAjustadasDropdown.Value);
                    app.ActualizarBrazoSeleccionado();
                    if (~strcmp(app.TrayectoriasCapturadasDropdown.Value,app.IIWARobotObject.NameDataInput))
                        prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Cargando trayectoria de entrada');
                        app.TrayectoriasCapturadasDropdown.Value=app.IIWARobotObject.NameDataInput;
                        [app.IDSpinner.Value, app.BrazoSwitch.Value]=app.IIWARobotObject.LoadInput(app.TrayectoriasCapturadasDropdown.Value);
                        app.ActualizarBrazoSeleccionado();
                        close(prg);
                    end
                    app.AjustarTrayectoriaVelocidadActual();
                    app.VelocidadSlider.Enable='on';
                    app.VelocidadLabel.Enable='on';
                    close(prg);
                catch ME
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            end
        end

        % Button pushed function: AjustarTrayectoriaButton
        function AjustarTrayectoriaButtonPushed(app, event)
            if (~strcmp(app.TrayectoriasCapturadasDropdown.Value, app.TrajectorySelectStr))
                prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Ajustando trayectoria');
                 try
                    app.GraficasPanel.Visible = 'on';
                    app.LimpiarGraficas();
                    [app.IDSpinner.Value, app.BrazoSwitch.Value, ~]=app.IIWARobotObject.BuildDataAdjusted(app.TrayectoriasCapturadasDropdown.Value, app.GraficasPanel);
                    app.ActualizarBrazoSeleccionado();
                    close(prg);
                    selection = uiconfirm(app.RoboespasAppUIFigure, '¿Guardar trayectoria ajustada?', '', 'Options', {'Si', 'No'});
                    if (strcmp(selection,'Si'))
                        prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Guardando trayectoria ajustada');
                        app.ActualizarTrayectoriasAjustadas();
                        app.TrayectoriasAjustadasDropdown.Value=app.IIWARobotObject.NameDataAdjusted;
                        app.VelocidadSlider.Value = app.DefaultVelocity;
                        app.VelocidadLabel.Text = [num2str(app.VelocidadSlider.Value), ' ', char(176), '/s'];
                        app.IIWARobotObject.ChangeVelocity(app.DefaultVelocity);
                        app.PosicionInicialReferenciaButton.Enable='on';
                        app.VelocidadSlider.Enable='on';
                        app.VelocidadLabel.Enable='on';
                        close(prg);
                        uialert(app.RoboespasAppUIFigure, ['Trayectoria ajustada guardada en archivo ', app.IIWARobotObject.NameDataAdjusted], '', 'Icon', 'success');    
                    else
                        app.IIWARobotObject.DeleteAdjustedTrajectory();
                        selection = uiconfirm(app.RoboespasAppUIFigure, '¿Borrar la trayectoria capturada?', '', 'Options', {'Si', 'No'});
                        if (strcmp(selection, 'Si'))
                            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Ajustando trayectoria');
                            app.IIWARobotObject.DeleteInputTrajectory();
                            app.ActualizarTrayectoriasCapturadas();
                            close(prg);
                            uialert(app.RoboespasAppUIFigure, ['Trayectoria de entrada ', app.IIWARobotObject.NameDataAdjusted, ' eliminada.'], '', 'Icon', 'success');
                        end
                    end
                catch ME
                    close(prg);
                    uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
                 end
            else
                uialert(app.RoboespasAppUIFigure, 'Seleccione una trayectoria primero', 'Error');
            end
        end

        % Button pushed function: PosicionInicialReferenciaButton
        function PosicionInicialReferenciaButtonPushed(app, event)
            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Moviendo robot a la posicion inicial de la trayectoria');
            try
                app.IIWARobotObject.SendInitialPosition();
                app.EjecutarTrayectoriaReferenciaButton.Enable='on';
                close(prg);
                uialert(app.RoboespasAppUIFigure, 'Robot en posición inicial', '', 'Icon', 'success');
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: PosicionInicialRepetirButton
        function PosicionInicialRepetirButtonPushed(app, event)
            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Moviendo robot a la posicion inicial de la trayectoria');
            try
                app.IIWARobotObject.SendInitialPosition();
                app.EjecutarTrayectoriaButton.Enable='on';
                close(prg);
                uialert(app.RoboespasAppUIFigure, 'Robot en posición inicial', '', 'Icon', 'success');
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: 
        % EjecutarTrayectoriaReferenciaButton
        function EjecutarTrayectoriaReferenciaButtonPushed(app, event)
            if(app.EMGTiempoRealButton.Value == 1)
                app.EMGTiempoRealButton.Value = 0;
                app.MostrarEMGTiempoReal(app.EMGTiempoRealButton.Value);
            end
            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve');
            try
                %Inicializar
                app.DelsysSensorsObject.StartSensors();
                app.FTSensorObject.StartSensor();
                
                %Iniciar captura de datos
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Iniciando captura sensor FT.';
                app.FTSensorObject.StartReference();
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Iniciando captura sensores Delsys.';
                app.DelsysSensorsObject.StartCapture();
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Ejecutando trayectoria!';
                %Ejecutar
                [app.IDSpinner.Value, app.BrazoSwitch.Value] = app.IIWARobotObject.SendReferenceTrajectory();
                app.ActualizarBrazoSeleccionado();
                %Parar captura de datos
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Parando captura sensores Delsys.';
                app.DelsysSensorsObject.EndReference();
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Parando captura sensor FT.';
                app.FTSensorObject.EndReference();
                %Limpiar captura y parar sensores
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Finalizando...';
                app.DelsysSensorsObject.StopSensors(); %13/07: Changed order Delsys/FT stop sensors
                app.FTSensorObject.StopSensor();
                app.FTSensorObject.GetReference();                
                %GUI update
                app.EjecutarTrayectoriaReferenciaButton.Enable='off';
                app.PosicionInicialRepetirButton.Enable='on';
                app.EMGTiempoRealButton.Enable = 'off';
                close(prg);
                uialert(app.RoboespasAppUIFigure, 'Trayectoria ejecutada en vacío el robot', 'Title', 'Icon', 'success');
            catch ME
               uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error'); 
            end       
        end

        % Button pushed function: EjecutarTrayectoriaButton
        function EjecutarTrayectoriaButtonPushed(app, event)
            if(app.EMGTiempoRealButton.Value == 1)
                app.EMGTiempoRealButton.Value = 0;
                app.MostrarEMGTiempoReal(app.EMGTiempoRealButton.Value);
            end
            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Title', ['Ejecutando trayectoria ', app.IIWARobotObject.NameData]);
            try
                app.IIWARobotObject.ClearTrials();
                app.DelsysSensorsObject.ClearTrials();
                app.FTSensorObject.StartSensor(); %Normally lasts for 4-5 seconds
                app.DelsysSensorsObject.StartSensors();
                for i_trial=1:app.RepeticionesSpinner.Value
                    %Inicializar  
                    str_repeticion=['Repetición ', num2str(i_trial), ' de ', num2str(app.RepeticionesSpinner.Value)];
                    if (i_trial~=1)
                        t_wait = app.TimeWaitBetweenRepetitions-toc;
                        prg.Message=['Esperando ', num2str(app.TimeWaitBetweenRepetitions), ' segundos de descanso.'];
                        pause(t_wait); %Pause for N seconds discounting the calculations time
                    end
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria'];
                    prg.Value=((i_trial-1)/(app.RepeticionesSpinner.Value*2)); 
                    
                    %Iniciar captura de datos
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Iniciando sensor FT.'];
                    app.FTSensorObject.StartTrial(i_trial);
                    app.ExecutionTimes{i_trial}.FT.Init=toc;
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Iniciando sensores Delsys.'];
                    app.DelsysSensorsObject.StartCapture();
                    app.ExecutionTimes{i_trial}.DelsysSensors.Init=toc;
                    %Ejecutar
                    app.ExecutionTimes{i_trial}.Robot.Call=toc;
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Moviendo robot.'];
                    [app.IDSpinner.Value, app.BrazoSwitch.Value]= app.IIWARobotObject.SendTrialTrajectory(i_trial);
                    app.ActualizarBrazoSeleccionado();
                    app.ExecutionTimes{i_trial}.Robot.End=toc;
                    
                    %Parar captura de datos
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Parando sensores Delsys.'];
                    app.DelsysSensorsObject.EndTrial(i_trial);
                    app.ExecutionTimes{i_trial}.DelsysSensors.End=toc;
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Parando sensor FT.'];
                    app.FTSensorObject.EndTrial(i_trial);
                    app.ExecutionTimes{i_trial}.FT.End=toc;

                    %Save the capture but not interpolate the data yet 
                    tic;
                    if (i_trial~=app.RepeticionesSpinner.Value)
                        %Move to initial position
                        prg.Message =[str_repeticion ': Moviendo a posición inicial']; 
                        app.IIWARobotObject.SendInitialPosition();
                        prg.Message=['Esperando ', num2str(app.TimeWaitBetweenRepetitions), ' segundos de descanso'];
                    end
                end
                                    
                %Parar captura FT/DelsysSensors
                app.DelsysSensorsObject.StopSensors();
                app.FTSensorObject.StopSensor();

                
                % Salvar resultados
                for i_trial=1:app.RepeticionesSpinner.Value
                    prg.Value=(((i_trial-1)+app.RepeticionesSpinner.Value)/(app.RepeticionesSpinner.Value*2)); 
                    prg.Message=['Salvando repetición ', num2str(i_trial), ' de ', num2str(app.RepeticionesSpinner.Value)];
                    app.GuardarTrial(i_trial);
                end
                prg.Message='Calculando repetibilidad';
                app.IIWARobotObject.GetMinMeanMax();
                %Actualizar GUI
                if (app.RepeticionesSpinner.Value==1)
                    app.RepeticionMostradaSpinner.Limits=[1 2];
                    app.RepeticionMostradaSpinner.Value=1;
                    app.RepeticionMostradaSpinner.Enable='off';
                else
                    app.RepeticionMostradaSpinner.Enable='on';
                    app.RepeticionMostradaSpinner.Value=1;
                    app.RepeticionMostradaSpinner.Limits=[0, app.RepeticionesSpinner.Value];
                end
                app.ResultsPanel.Visible='on';
                app.EjecutarTrayectoriaButton.Enable='off';
                close(prg); 
                uialert(app.RoboespasAppUIFigure, 'Trayectoria ejecutada en el robot', 'Title', 'Icon', 'success', 'CloseFcn', 'uiresume(gcbf)');
                uiwait(gcbf);
                app.MenuTabGroup.SelectedTab = app.ResultadosTab;
            catch ME
                close(prg);
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: qButton
        function qButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.LimpiarGraficas();
                drawnow;
                app.IIWARobotObject.PlotJointPositionTrajectories(app.RepeticionMostradaSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: dqButton
        function dqButtonPushed(app, event)
            try
                app.LimpiarGraficas();
                drawnow;
                app.IIWARobotObject.PlotJointVelocityTrajectories(app.RepeticionMostradaSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: ddqButton
        function ddqButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.LimpiarGraficas();
                drawnow;
                app.IIWARobotObject.PlotJointAccelerationTrajectories( app.RepeticionMostradaSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: ErqButton
        function ErqButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.LimpiarGraficas();
                drawnow;
                app.IIWARobotObject.PlotJointPositionRepeatibilityError(app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: ErdqButton
        function ErdqButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.LimpiarGraficas();
                drawnow;
                app.IIWARobotObject.PlotJointVelocityRepeatibilityError(app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EpqButton
        function EpqButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.LimpiarGraficas();
                drawnow;
                app.IIWARobotObject.PlotJointPositionError(app.RepeticionMostradaSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EpdqButton
        function EpdqButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.LimpiarGraficas();
                drawnow;
                app.IIWARobotObject.PlotJointVelocityError(app.RepeticionMostradaSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.LimpiarGraficas();
                drawnow;
                app.IIWARobotObject.PlotCartesianPositionTrajectories(app.RepeticionMostradaSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: dxButton
        function dxButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.LimpiarGraficas();
                drawnow;
                app.IIWARobotObject.PlotCartesianVelocityTrajectories(app.RepeticionMostradaSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: ErxButton
        function ErxButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.LimpiarGraficas();
                drawnow;
                app.IIWARobotObject.PlotCartesianPositionRepeatibilityError(app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EpxButton
        function EpxButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.LimpiarGraficas();
                drawnow;
                app.IIWARobotObject.PlotCartesianPositionError(app.RepeticionMostradaSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EpdxButton
        function EpdxButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.LimpiarGraficas();
                drawnow;
                app.IIWARobotObject.PlotCartesianVelocityError(app.RepeticionMostradaSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: TresDButton
        function TresDButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.LimpiarGraficas();
                drawnow;
                app.IIWARobotObject.Plot3DCartesianPositionTrajectories(app.RepeticionMostradaSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: FTButton
        function FTButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.LimpiarGraficas();
                drawnow;
                app.FTSensorObject.PlotFT(app.RepeticionMostradaSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: VelocidadAngularButton
        function VelocidadAngularButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.LimpiarGraficas();
                drawnow;
                app.DelsysSensorsObject.PlotGYRO(app.RepeticionMostradaSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EMGButton
        function EMGButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.LimpiarGraficas();
                drawnow;
                app.DelsysSensorsObject.PlotEMG(app.RepeticionMostradaSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: AceleracionesButton
        function AceleracionesButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.LimpiarGraficas();
                drawnow;
                app.DelsysSensorsObject.PlotACC(app.RepeticionMostradaSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: ErdxButton
        function ErdxButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.LimpiarGraficas();
                drawnow;
                app.IIWARobotObject.PlotCartesianVelocityRepeatibilityError(app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: TqButton
        function TqButtonPushed(app, event)
            try
                app.GraficasPanel.Visible='on';
                app.LimpiarGraficas();
                drawnow;
                app.IIWARobotObject.PlotJointTorques(app.RepeticionMostradaSpinner.Value, app.GraficasPanel);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: FTIIWAButton
        function FTIIWAButtonPushed(app, event)
            app.IIWARobotObject.PlotCartesianForceTorque(app.RepeticionMostradaSpinner.Value, app.GraficasPanel);
        end

        % Close request function: RoboespasAppUIFigure
        function RoboespasAppUIFigureCloseRequest(app, event)
            try
                if (~isempty(app.DelsysSensorsObject))
                    app.DelsysSensorsObject.CloseSensors();
                end
                delete(app);
            catch ME
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Value changed function: IIWACheckBox
        function IIWACheckBoxChanged(app, event)
            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Inicializando IIWA');
            try
                app.ActivarBotonesGraficas('iiwa', app.IIWACheckBox.Value);
                if (app.IIWACheckBox.Value==1)
                    commandMode = app.IIWARobotObject.ConnectROS();
                    if (strcmp(commandMode, 'FRI'))
                        app.IiwaCommandSwitch.Value = 'FRI';
                        app.FRILabel.Enable='on';
                        app.StackLabel.Enable='off';
                    elseif (strcmp(commandMode, 'Stack'))
                        app.IiwaCommandSwitch.Value='Stack';
                        app.FRILabel.Enable='off';
                        app.StackLabel.Enable='on';
                    end
                else
                    app.FRILabel.Enable='off';
                    app.StackLabel.Enable='off';
                end
                app.ResetRepeatButtons();
                app.ResetCaptureButtons();
                prg.close();
            catch ME
                app.IIWACheckBox.Value = 0;
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error'); 
            end
            
        end

        % Value changed function: DelsysCheckBox
        function DelsysCheckBoxChanged(app, event)
            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Inicializando sensores Delsys');
            try
                if (~isempty(app.DelsysSensorsObject))
                    app.DelsysSensorsObject.CloseSensors();
                end
                app.DelsysSensorsObject = DelsysSensors(app.DelsysCheckBox.Value);
                app.DelsysSensorsObject.SetMovement(app.NameMovement);
                app.ActivarBotonesGraficas('delsys', app.DelsysCheckBox.Value);
                prg.close();
            catch ME
                app.DelsysCheckBox.Value = 0;
                prg.close();
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error'); 
            end
                
        end

        % Value changed function: FTSensorCheckBox
        function FTSensorCheckBoxChanged(app, event)
            prg=uiprogressdlg(app.RoboespasAppUIFigure, 'Message', 'Inicializando sensor F/T');
            try
                app.FTSensorObject = FTSensor(app.FTSensorCheckBox.Value);
                app.ActivarBotonesGraficas('ft', app.FTSensorCheckBox.Value);
                prg.close();
            catch ME
                app.FTSensorCheckBox.Value = 0;
                prg.close();
                uialert(app.RoboespasAppUIFigure, [ME.identifier, ': ', ME.message], 'Error'); 
            end
            
        end

        % Value changed function: RepeticionMostradaSpinner
        function RepeticionMostradaSpinnerValueChanged(app, event)
            value = app.RepeticionMostradaSpinner.Value;
            if (value == 0) && (app.IIWACheckBox.Value == 1)
                app.ErqButton.Enable = 'on';
                app.ErdqButton.Enable = 'on';
                app.ErxButton.Enable = 'on';
                app.ErdxButton.Enable = 'on';
                app.TqButton.Enable = 'on';
                app.FTIIWAButton.Enable='on';
            else
                app.ErqButton.Enable = 'off';
                app.ErdqButton.Enable = 'off';
                app.ErxButton.Enable = 'off';
                app.ErdxButton.Enable = 'off';
                app.TqButton.Enable = 'off';
                app.FTIIWAButton.Enable='off';
            end
        end

        % Callback function
        function CloseImageClicked(app, event)
            app.MenuTabGroup.SelectedTab = app.ResultadosTab;
            app.MenuTabGroupChanged();
        end

        % Callback function
        function SegmentsValueChanged(app, event)
            app.IIWARobotObject.SetCommandedPPSegments(app.CommandedSegments.Value);      
        end

        % Value changed function: PausaEntreFlexionExtension
        function PausaEntreFlexionExtensionValueChanged(app, event)
            app.IIWARobotObject.SetCommandedMiddlePause(app.PausaEntreFlexionExtension.Value);
        end

        % Value changed function: PausaEntreFlexoextensiones
        function PauseBetweenTrialsChanged(app, event)
            app.TimeWaitBetweenRepetitions = app.PausaEntreFlexoextensiones.Value;
        end

        % Callback function
        function DeleteInputTrajectory(app, event)
            app.IIWARobotObject.DeleteInputTrajectory();
            app.ActualizarTrayectoriasCapturadas();
        end

        % Callback function
        function DeleteAdjustedTrajectory(app, event)
            app.IIWARobotObject.DeleteAdjustedTrajectory();
            app.ActualizarTrayectoriasAjustadas();
        end

        % Value changed function: IiwaCommandSwitch
        function IiwaCommandSwitchValueChanged(app, event)
            if(strcmp(app.IIWARobotObject.CommandMode, 'Stack'))
                app.IIWARobotObject.CommandMode='FRI';
            elseif (strcmp(app.IIWARobotObject.CommandMode, 'FRI'))
                app.IIWARobotObject.CommandMode='Stack';
            else
                uialert(app.RoboespasAppUIFigure, 'Wrong initial CommandMode in IIWAControlObject', 'Error'); 
            end
        end

        % Value changed function: EMGTiempoRealButton
        function EMGTiempoRealButtonValueChanged(app, event)
            value = app.EMGTiempoRealButton.Value;
            if(app.DelsysCheckBox.Value)
                app.MostrarEMGTiempoReal(value);
            else
                app.EMGTiempoRealButton.Value = 0;
            end
        end

        % Image clicked function: EMGAyudaCapturarImage, 
        % EMGAyudaRepetirImage
        function EMGHelpImageClicked(app, event)
            if(strcmp(app.GraficasPanel.Visible,'on'))
                app.GraficasPanel.Visible='off';
                app.SensoresDelsysPanel.Visible='on';
            else
                app.GraficasPanel.Visible='on';
                app.SensoresDelsysPanel.Visible='off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RoboespasAppUIFigure = uifigure('Visible', 'off');
            app.RoboespasAppUIFigure.Name = 'RoboespasApp';
            app.RoboespasAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @RoboespasAppUIFigureCloseRequest, true);
            app.MenuTabGroup = uitabgroup(app.RoboespasAppUIFigure);
            app.MenuTabGroup.SelectionChangedFcn = createCallbackFcn(app, @MenuTabGroupChanged, true);
            app.CapturarTab = uitab(app.MenuTabGroup);
            app.CapturarTab.Title = 'Capturar';
            app.MoverIniLabel = uilabel(app.CapturarTab);
            app.MoverIniLabel.Text = '1. Mover a la posición inicial';
            app.PersonalizarIniLabel = uilabel(app.CapturarTab);
            app.PersonalizarIniLabel.Text = '2. Personalizar posición inicial';
            app.GrabarFlexionLabel = uilabel(app.CapturarTab);
            app.GrabarFlexionLabel.Text = '3. Grabar y salvar trayectoria de flexión';
            app.MoverIzqdaButton = uibutton(app.CapturarTab, 'push');
            app.MoverIzqdaButton.ButtonPushedFcn = createCallbackFcn(app, @MoverPrefIzqdaButton, true);
            app.MoverIzqdaButton.Text = 'Izquierda';
            app.MoverDchaButton = uibutton(app.CapturarTab, 'push');
            app.MoverDchaButton.ButtonPushedFcn = createCallbackFcn(app, @MoverPrefDchaButton, true);
            app.MoverDchaButton.Text = 'Derecha';
            app.GravedadCompensadaButtonGroup = uibuttongroup(app.CapturarTab);
            app.GravedadCompensadaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GravedadCompensadaButtonGroupSelectionChanged, true);
            app.IniBloquearRobotButton = uitogglebutton(app.GravedadCompensadaButtonGroup);
            app.IniBloquearRobotButton.Enable = 'off';
            app.IniBloquearRobotButton.Text = 'Bloquear';
            app.IniBloquearRobotButton.Value = true;
            app.LiberarXButton = uitogglebutton(app.GravedadCompensadaButtonGroup);
            app.LiberarXButton.Enable = 'off';
            app.LiberarXButton.Text = 'Liberar X';
            app.LiberarYButton = uitogglebutton(app.GravedadCompensadaButtonGroup);
            app.LiberarYButton.Enable = 'off';
            app.LiberarYButton.Text = 'Liberar Y';
            app.LiberarZButton = uitogglebutton(app.GravedadCompensadaButtonGroup);
            app.LiberarZButton.Enable = 'off';
            app.LiberarZButton.Text = 'Liberar Z';
            app.CapturarButtonGroup = uibuttongroup(app.CapturarTab);
            app.CapturarButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CapturarButtonGroupSelectionChanged, true);
            app.CapturarBloquearButton = uitogglebutton(app.CapturarButtonGroup);
            app.CapturarBloquearButton.Enable = 'off';
            app.CapturarBloquearButton.Text = 'Bloquear';
            app.CapturarBloquearButton.Value = true;
            app.IniciarCapturaButton = uitogglebutton(app.CapturarButtonGroup);
            app.IniciarCapturaButton.Enable = 'off';
            app.IniciarCapturaButton.Text = 'Iniciar';
            app.FinalizarCapturaButton = uitogglebutton(app.CapturarButtonGroup);
            app.FinalizarCapturaButton.Enable = 'off';
            app.FinalizarCapturaButton.Text = 'Finalizar';
            app.GenerarFlexoextensionLabel = uilabel(app.CapturarTab);
            app.GenerarFlexoextensionLabel.Text = '4. Generar trayectoria de flexo-extensión';
            app.AjustarTrayectoriaButton = uibutton(app.CapturarTab, 'push');
            app.AjustarTrayectoriaButton.ButtonPushedFcn = createCallbackFcn(app, @AjustarTrayectoriaButtonPushed, true);
            app.AjustarTrayectoriaButton.Enable = 'off';
            app.AjustarTrayectoriaButton.Text = 'Ajustar Trayectoria';
            app.TrayectoriasCapturadasDropdown = uidropdown(app.CapturarTab);
            app.TrayectoriasCapturadasDropdown.Items = {'Seleccionar trayectoria'};
            app.TrayectoriasCapturadasDropdown.ValueChangedFcn = createCallbackFcn(app, @TrayectoriasCapturadasDropdownChanged, true);
            app.TrayectoriasCapturadasDropdown.Value = 'Seleccionar trayectoria';
            app.EMGAyudaCapturarImage = uiimage(app.CapturarTab);
            app.EMGAyudaCapturarImage.ImageClickedFcn = createCallbackFcn(app, @EMGHelpImageClicked, true);
            app.EMGAyudaCapturarImage.ImageSource = 'EMG.png';
            app.RepetirTab = uitab(app.MenuTabGroup);
            app.RepetirTab.Title = 'Repetir';
            app.AjustesLabel = uilabel(app.RepetirTab);
            app.AjustesLabel.Text = '2. Ajustes';
            app.EjecutarReferenciaLabel = uilabel(app.RepetirTab);
            app.EjecutarReferenciaLabel.Text = '3. Ejecutar trayectoria de referencia';
            app.PosicionInicialReferenciaButton = uibutton(app.RepetirTab, 'push');
            app.PosicionInicialReferenciaButton.ButtonPushedFcn = createCallbackFcn(app, @PosicionInicialReferenciaButtonPushed, true);
            app.PosicionInicialReferenciaButton.Enable = 'off';
            app.PosicionInicialReferenciaButton.Text = 'Mandar a la Posición Inicial';
            app.EjecutarTrayectoriaReferenciaButton = uibutton(app.RepetirTab, 'push');
            app.EjecutarTrayectoriaReferenciaButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarTrayectoriaReferenciaButtonPushed, true);
            app.EjecutarTrayectoriaReferenciaButton.Enable = 'off';
            app.EjecutarTrayectoriaReferenciaButton.Text = 'Ejecutar Trayectoria en Vacío';
            app.EjecutarTrayectoriaLabel = uilabel(app.RepetirTab);
            app.EjecutarTrayectoriaLabel.Text = '4. Ejecutar trayectoria';
            app.PosicionInicialRepetirButton = uibutton(app.RepetirTab, 'push');
            app.PosicionInicialRepetirButton.ButtonPushedFcn = createCallbackFcn(app, @PosicionInicialRepetirButtonPushed, true);
            app.PosicionInicialRepetirButton.Enable = 'off';
            app.PosicionInicialRepetirButton.Text = 'Mandar a la Posición Inicial';
            app.EjecutarTrayectoriaButton = uibutton(app.RepetirTab, 'push');
            app.EjecutarTrayectoriaButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarTrayectoriaButtonPushed, true);
            app.EjecutarTrayectoriaButton.Enable = 'off';
            app.EjecutarTrayectoriaButton.Text = 'Ejecutar Trayectoria';
            app.RepeticionesSpinner = uispinner(app.RepetirTab);
            app.RepeticionesSpinner.Limits = [1 100];
            app.RepeticionesSpinner.RoundFractionalValues = 'on';
            app.RepeticionesSpinner.Value = 1;
            app.VelocidadSlider = uislider(app.RepetirTab);
            app.VelocidadSlider.Limits = [0 45];
            app.VelocidadSlider.MajorTicks = [0 30 60 90 120 150 180 210];
            app.VelocidadSlider.MajorTickLabels = {'0', '30', '60', '90', '120', '150', '180', '210'};
            app.VelocidadSlider.ValueChangedFcn = createCallbackFcn(app, @VelocidadSliderChanged, true);
            app.VelocidadSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125 130 135 140 145 150 155 160 165 170 175 180 185 190 195 200 205 210];
            app.VelocidadSlider.Enable = 'off';
            app.VelocidadSlider.Value = 45;
            app.VelocidadLabel = uilabel(app.RepetirTab);
            app.VelocidadLabel.Enable = 'off';
            app.VelocidadLabel.Text = 'º/s';
            app.TrayectoriasAjustadasDropdown = uidropdown(app.RepetirTab);
            app.TrayectoriasAjustadasDropdown.Items = {'Seleccionar trayectoria'};
            app.TrayectoriasAjustadasDropdown.ValueChangedFcn = createCallbackFcn(app, @TrayectoriasAjustadasDropdownChanged, true);
            app.TrayectoriasAjustadasDropdown.Value = 'Seleccionar trayectoria';
            app.SeleccionarTrayectoriaLabel = uilabel(app.RepetirTab);
            app.SeleccionarTrayectoriaLabel.Text = '1. Seleccionar trayectoria a repetir';
            app.TentreflexionyextensionsLabel = uilabel(app.RepetirTab);
            app.TentreflexionyextensionsLabel.Text = 'T entre flexion y extension (s)';
            app.PausaEntreFlexionExtension = uieditfield(app.RepetirTab, 'numeric');
            app.PausaEntreFlexionExtension.ValueChangedFcn = createCallbackFcn(app, @PausaEntreFlexionExtensionValueChanged, true);
            app.TentreflexoextensionessLabel = uilabel(app.RepetirTab);
            app.TentreflexoextensionessLabel.Text = 'T entre flexo-extensiones (s)';
            app.PausaEntreFlexoextensiones = uispinner(app.RepetirTab);
            app.PausaEntreFlexoextensiones.Step = 0.1;
            app.PausaEntreFlexoextensiones.Limits = [0 60];
            app.PausaEntreFlexoextensiones.ValueChangedFcn = createCallbackFcn(app, @PauseBetweenTrialsChanged, true);
            app.PausaEntreFlexoextensiones.Value = 6;
            app.EMGTiempoRealButton = uibutton(app.RepetirTab, 'state');
            app.EMGTiempoRealButton.ValueChangedFcn = createCallbackFcn(app, @EMGTiempoRealButtonValueChanged, true);
            app.EMGTiempoRealButton.Enable = 'off';
            app.EMGTiempoRealButton.Text = 'EMGs';
            app.EMGAyudaRepetirImage = uiimage(app.RepetirTab);
            app.EMGAyudaRepetirImage.ImageClickedFcn = createCallbackFcn(app, @EMGHelpImageClicked, true);
            app.EMGAyudaRepetirImage.ImageSource = 'EMG.png';
            app.ResultadosTab = uitab(app.MenuTabGroup);
            app.ResultadosTab.Title = 'Resultados';
            app.ResultsPanel = uipanel(app.ResultadosTab);
            app.TrayectoriaIIWALabel = uilabel(app.ResultsPanel);
            app.TrayectoriaIIWALabel.Text = 'Trayectoria IIWA';
            app.ArticularLabel = uilabel(app.ResultsPanel);
            app.ArticularLabel.Text = 'Articular';
            app.qButton = uibutton(app.ResultsPanel, 'push');
            app.qButton.ButtonPushedFcn = createCallbackFcn(app, @qButtonPushed, true);
            app.qButton.Enable = 'off';
            app.qButton.Tooltip = {'Posición articular'};
            app.qButton.Text = 'q';
            app.dqButton = uibutton(app.ResultsPanel, 'push');
            app.dqButton.ButtonPushedFcn = createCallbackFcn(app, @dqButtonPushed, true);
            app.dqButton.Enable = 'off';
            app.dqButton.Tooltip = {'Velocidad articular'};
            app.dqButton.Text = 'dq';
            app.ErqButton = uibutton(app.ResultsPanel, 'push');
            app.ErqButton.ButtonPushedFcn = createCallbackFcn(app, @ErqButtonPushed, true);
            app.ErqButton.Enable = 'off';
            app.ErqButton.Tooltip = {'Error de repetibilidad de posición articular entre trayectorias ejecutadas'};
            app.ErqButton.Text = 'Er(q)';
            app.ErdqButton = uibutton(app.ResultsPanel, 'push');
            app.ErdqButton.ButtonPushedFcn = createCallbackFcn(app, @ErdqButtonPushed, true);
            app.ErdqButton.Enable = 'off';
            app.ErdqButton.Tooltip = {'Error de repetibilidad de velocidad articular entre trayectorias ejecutadas'};
            app.ErdqButton.Text = 'Er(dq)';
            app.CartesianaLabel = uilabel(app.ResultsPanel);
            app.CartesianaLabel.Text = 'Cartesiana';
            app.xButton = uibutton(app.ResultsPanel, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Enable = 'off';
            app.xButton.Tooltip = {'Posición cartesiana'};
            app.xButton.Text = 'x';
            app.ErxButton = uibutton(app.ResultsPanel, 'push');
            app.ErxButton.ButtonPushedFcn = createCallbackFcn(app, @ErxButtonPushed, true);
            app.ErxButton.Enable = 'off';
            app.ErxButton.Tooltip = {'Error de repetibilidad de posición cartesiana entre trayectorias ejecutadas'};
            app.ErxButton.Text = 'Er(x)';
            app.ErdxButton = uibutton(app.ResultsPanel, 'push');
            app.ErdxButton.ButtonPushedFcn = createCallbackFcn(app, @ErdxButtonPushed, true);
            app.ErdxButton.Enable = 'off';
            app.ErdxButton.Tooltip = {'Error de repetibilidad de velocidad cartesiana entre trayectorias ejecutadas'};
            app.ErdxButton.Text = 'Er(dx)';
            app.TresDButton = uibutton(app.ResultsPanel, 'push');
            app.TresDButton.ButtonPushedFcn = createCallbackFcn(app, @TresDButtonPushed, true);
            app.TresDButton.Enable = 'off';
            app.TresDButton.Tooltip = {'Posición cartesiana en 3D'};
            app.TresDButton.Text = '3D';
            app.dxButton = uibutton(app.ResultsPanel, 'push');
            app.dxButton.ButtonPushedFcn = createCallbackFcn(app, @dxButtonPushed, true);
            app.dxButton.Enable = 'off';
            app.dxButton.Tooltip = {'Velocidad cartesiana'};
            app.dxButton.Text = 'dx';
            app.SensorFuerzaParLabel = uilabel(app.ResultsPanel);
            app.SensorFuerzaParLabel.Text = 'Sensor Fuerza Par';
            app.FTButton = uibutton(app.ResultsPanel, 'push');
            app.FTButton.ButtonPushedFcn = createCallbackFcn(app, @FTButtonPushed, true);
            app.FTButton.Enable = 'off';
            app.FTButton.Text = 'FT';
            app.SensoresDelsysLabel = uilabel(app.ResultsPanel);
            app.SensoresDelsysLabel.Text = 'Sensores Delsys';
            app.EMGButton = uibutton(app.ResultsPanel, 'push');
            app.EMGButton.ButtonPushedFcn = createCallbackFcn(app, @EMGButtonPushed, true);
            app.EMGButton.Enable = 'off';
            app.EMGButton.Text = 'EMG';
            app.AceleracionesButton = uibutton(app.ResultsPanel, 'push');
            app.AceleracionesButton.ButtonPushedFcn = createCallbackFcn(app, @AceleracionesButtonPushed, true);
            app.AceleracionesButton.Enable = 'off';
            app.AceleracionesButton.Text = 'Aceleraciones';
            app.VelocidadAngularButton = uibutton(app.ResultsPanel, 'push');
            app.VelocidadAngularButton.ButtonPushedFcn = createCallbackFcn(app, @VelocidadAngularButtonPushed, true);
            app.VelocidadAngularButton.Enable = 'off';
            app.VelocidadAngularButton.Text = 'Velocidad Angular';
            app.ddqButton = uibutton(app.ResultsPanel, 'push');
            app.ddqButton.ButtonPushedFcn = createCallbackFcn(app, @ddqButtonPushed, true);
            app.ddqButton.Enable = 'off';
            app.ddqButton.Tooltip = {'Aceleración articular'};
            app.ddqButton.Text = 'ddq';
            app.SeleccionelarepeticinamostrarLabel = uilabel(app.ResultsPanel);
            app.SeleccionelarepeticinamostrarLabel.Text = 'Seleccione la repetición a mostrar';
            app.RepeticionMostradaSpinner = uispinner(app.ResultsPanel);
            app.RepeticionMostradaSpinner.Limits = [1 10];
            app.RepeticionMostradaSpinner.RoundFractionalValues = 'on';
            app.RepeticionMostradaSpinner.ValueChangedFcn = createCallbackFcn(app, @RepeticionMostradaSpinnerValueChanged, true);
            app.RepeticionMostradaSpinner.Tooltip = {'Seleccione ''0'' para comparar todas las repeticiones entre si (repetibilidad) y con la trayectoria comandada (precision).'};
            app.RepeticionMostradaSpinner.Value = 1;
            app.EpxButton = uibutton(app.ResultsPanel, 'push');
            app.EpxButton.ButtonPushedFcn = createCallbackFcn(app, @EpxButtonPushed, true);
            app.EpxButton.Enable = 'off';
            app.EpxButton.Tooltip = {'Error de posición cartesiana entre la trayectoria comandada y la ejecutada'};
            app.EpxButton.Text = 'Ep(x)';
            app.EpdxButton = uibutton(app.ResultsPanel, 'push');
            app.EpdxButton.ButtonPushedFcn = createCallbackFcn(app, @EpdxButtonPushed, true);
            app.EpdxButton.Enable = 'off';
            app.EpdxButton.Tooltip = {'Error de velocidad cartesiana entre la trayectoria comandada y la ejecutada'};
            app.EpdxButton.Text = 'Ep(dx)';
            app.RepetibilidadCartesianaLabel = uilabel(app.ResultsPanel);
            app.RepetibilidadCartesianaLabel.Tooltip = {'Si se ha seleccionado la repeticion ''0'''; ' muestra el error cartesiano minimo'; ' maximo y medio entre la trayect'};
            app.RepetibilidadCartesianaLabel.Text = 'Repetibilidad: ';
            app.PrecisionCartesianaLabel = uilabel(app.ResultsPanel);
            app.PrecisionCartesianaLabel.Tooltip = {'Para cada trayectoria muestra el error cartesiano entre la misma y la trayectoria comandada. Si se ha seleccionado la repeticion ''0''  muestra el error cartesiano entre la trayectoria media seguida y la trayectoria comandada.'};
            app.PrecisionCartesianaLabel.Text = 'Precisión:';
            app.RepetibilidadArticularLabel = uilabel(app.ResultsPanel);
            app.RepetibilidadArticularLabel.Tooltip = {'Si se ha seleccionado la repeticion ''0'''; ' muestra el error articular minimo'; ' maximo y medio entre la trayectoria media ejecutada y cada una de las repeticiones ejecutadas. '};
            app.RepetibilidadArticularLabel.Text = 'Repetibilidad:';
            app.PrecisionArticularLabel = uilabel(app.ResultsPanel);
            app.PrecisionArticularLabel.Tooltip = {'Para cada trayectoria'; ' calcula el error articularentre la misma y la trayectoria comandada. Si se ha seleccionado la repeticion ''0'' muestra el error articular entre la  trayectoria media seguida y la trayectoria comandada.'};
            app.PrecisionArticularLabel.Text = 'Precisión:';
            app.EpqButton = uibutton(app.ResultsPanel, 'push');
            app.EpqButton.ButtonPushedFcn = createCallbackFcn(app, @EpqButtonPushed, true);
            app.EpqButton.Enable = 'off';
            app.EpqButton.Tooltip = {'Error de posición articular entre la trayectoria comandada y la ejecutada'};
            app.EpqButton.Text = 'Ep(q)';
            app.EpdqButton = uibutton(app.ResultsPanel, 'push');
            app.EpdqButton.ButtonPushedFcn = createCallbackFcn(app, @EpdqButtonPushed, true);
            app.EpdqButton.Enable = 'off';
            app.EpdqButton.Tooltip = {'Error de velocidad articular entre la trayectoria comandada y la ejecutada'};
            app.EpdqButton.Text = 'Ep(dq)';
            app.TqButton = uibutton(app.ResultsPanel, 'push');
            app.TqButton.ButtonPushedFcn = createCallbackFcn(app, @TqButtonPushed, true);
            app.TqButton.Enable = 'off';
            app.TqButton.Text = 'Tq';
            app.FTIIWAButton = uibutton(app.ResultsPanel, 'push');
            app.FTIIWAButton.ButtonPushedFcn = createCallbackFcn(app, @FTIIWAButtonPushed, true);
            app.FTIIWAButton.Enable = 'off';
            app.FTIIWAButton.Text = 'F/T';
            app.Repeticion0Info = uilabel(app.ResultsPanel);
            app.Repeticion0Info.Text = '(0 para compararlas entre sí)';
            app.PacientePanel = uipanel(app.RoboespasAppUIFigure);
            app.PacientePanel.Title = 'Paciente';
            app.IDSpinnerLabel = uilabel(app.PacientePanel);
            app.IDSpinnerLabel.Enable = 'off';
            app.IDSpinnerLabel.Text = 'ID';
            app.IDSpinner = uispinner(app.PacientePanel);
            app.IDSpinner.Limits = [1 100];
            app.IDSpinner.RoundFractionalValues = 'on';
            app.IDSpinner.Enable = 'off';
            app.IDSpinner.Value = 1;
            app.BrazoPanel = uipanel(app.RoboespasAppUIFigure);
            app.BrazoPanel.Title = 'Brazo ';
            app.BrazoSwitch = uiswitch(app.BrazoPanel, 'slider');
            app.BrazoSwitch.Items = {'I', 'D'};
            app.BrazoSwitch.Enable = 'off';
            app.BrazoSwitch.Value = 'I';
            app.BrazoSwitchDLabel = uilabel(app.BrazoPanel);
            app.BrazoSwitchDLabel.Enable = 'off';
            app.BrazoSwitchDLabel.Text = 'D';
            app.BrazoSwitchILabel = uilabel(app.BrazoPanel);
            app.BrazoSwitchILabel.Enable = 'off';
            app.BrazoSwitchILabel.Text = 'I';
            app.IiwaCommandPanel = uipanel(app.RoboespasAppUIFigure);
            app.IiwaCommandPanel.Title = 'IiwaCommand';
            app.IiwaCommandSwitch = uiswitch(app.IiwaCommandPanel, 'slider');
            app.IiwaCommandSwitch.Items = {'Stack', 'FRI'};
            app.IiwaCommandSwitch.ValueChangedFcn = createCallbackFcn(app, @IiwaCommandSwitchValueChanged, true);
            app.IiwaCommandSwitch.Enable = 'off';
            app.IiwaCommandSwitch.Value = 'FRI';
            app.FRILabel = uilabel(app.IiwaCommandPanel);
            app.FRILabel.Enable = 'off';
            app.FRILabel.Text = 'FRI';
            app.StackLabel = uilabel(app.IiwaCommandPanel);
            app.StackLabel.Enable = 'off';
            app.StackLabel.Text = 'Stack';
            app.ConexionesPanel = uipanel(app.RoboespasAppUIFigure);
            app.ConexionesPanel.Title = 'Conexiones';
            app.IIWACheckBox = uicheckbox(app.ConexionesPanel);
            app.IIWACheckBox.ValueChangedFcn = createCallbackFcn(app, @IIWACheckBoxChanged, true);
            app.IIWACheckBox.Text = 'IIWA';
            app.DelsysCheckBox = uicheckbox(app.ConexionesPanel);
            app.DelsysCheckBox.ValueChangedFcn = createCallbackFcn(app, @DelsysCheckBoxChanged, true);
            app.DelsysCheckBox.Text = 'Delsys';
            app.FTSensorCheckBox = uicheckbox(app.ConexionesPanel);
            app.FTSensorCheckBox.ValueChangedFcn = createCallbackFcn(app, @FTSensorCheckBoxChanged, true);
            app.FTSensorCheckBox.Text = 'FTSensor';
            app.RoboespasLogo = uiimage(app.RoboespasAppUIFigure);
            app.RoboespasLogo.ImageSource = 'roboespas_logo.jpg';
            app.UC3MLogo = uiimage(app.RoboespasAppUIFigure);
            app.UC3MLogo.ImageSource = 'uc3m_logo.jpg';
            app.SensoresDelsysPanel = uipanel(app.RoboespasAppUIFigure);
            app.ImagenS1 = uiimage(app.SensoresDelsysPanel);
            app.ImagenS1.ImageSource = 'S1.PNG';
            app.ImagenS2 = uiimage(app.SensoresDelsysPanel);
            app.ImagenS2.ImageSource = 'S2.PNG';
            app.ImagenS3 = uiimage(app.SensoresDelsysPanel);
            app.ImagenS3.ImageSource = 'S3.PNG';
            app.ImagenS4 = uiimage(app.SensoresDelsysPanel);
            app.ImagenS4.ImageSource = 'S4.PNG';
            app.S3BrachioradialisLabel = uilabel(app.SensoresDelsysPanel);
            app.S3BrachioradialisLabel.Text = 'S3 - Brachioradialis';
            app.S4FlexorCarpiRadialisLabel = uilabel(app.SensoresDelsysPanel);
            app.S4FlexorCarpiRadialisLabel.Text = 'S4 - Flexor Carpi Radialis';
            app.S1BicepsBrachiiLabel = uilabel(app.SensoresDelsysPanel);
            app.S1BicepsBrachiiLabel.Text = 'S1 - Biceps Brachii';
            app.S2TricepsLateralHeadLabel = uilabel(app.SensoresDelsysPanel);
            app.S2TricepsLateralHeadLabel.Text = 'S2 - Triceps Lateral Head';
            app.ColocacionSensoresDelsysLabel = uilabel(app.SensoresDelsysPanel);
            app.ColocacionSensoresDelsysLabel.Text = 'Colocación Sensores Delsys en Brazo Derecho';
            app.GraficasPanel = uipanel(app.RoboespasAppUIFigure);
            app.GraficasPanel.Visible = 'off';
            % Show the figure after all components are created
            app.RoboespasAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoboespasApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RoboespasAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RoboespasAppUIFigure)
        end
    end
end